classdef FSofCosineSig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        inradiansLabel         matlab.ui.control.Label
        EditField_7            matlab.ui.control.NumericEditField
        DefineabsolutevaluerangefortimeLabel  matlab.ui.control.Label
        EditField_6            matlab.ui.control.NumericEditField
        EnterAinHzandLabel     matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Examplext3cos05tLabel  matlab.ui.control.Label
        CopyRightSoosanBeheshtiYounesSadatNejadLabel  matlab.ui.control.Label
        SignalsSystemsIRyersonUniversityLabel  matlab.ui.control.Label
        FourierSeriesofaCosineSignalTwoSpiralsLabel  matlab.ui.control.Label
        EditField_5            matlab.ui.control.NumericEditField
        ComputeButton          matlab.ui.control.Button
        EditField_3            matlab.ui.control.NumericEditField
        xtAcostLabel           matlab.ui.control.Label
        xtcostLabel            matlab.ui.control.Label
        UIAxes2_10             matlab.ui.control.UIAxes
        UIAxes2_9              matlab.ui.control.UIAxes
        UIAxes2_8              matlab.ui.control.UIAxes
        UIAxes2_7              matlab.ui.control.UIAxes
        UIAxes2_6              matlab.ui.control.UIAxes
        UIAxes2_5              matlab.ui.control.UIAxes
        UIAxes2_4              matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            text(app.UIAxes2,'Interpreter','LaTex',...
    'string','$2 \pi \alpha$',...
    'FontSize',20,'color','black');


%text(app.UIAxes2_3,'Interpreter','LaTex',...
 %   'string','$\pi$',...
  %  'FontSize',20,'color',[0.50,0.50,0.50]);
text(app.UIAxes2_4,'Interpreter','LaTex',...
    'string','$\alpha$',...
    'FontSize',20,'color','black');

text(app.UIAxes2_5,'Interpreter','LaTex',...
    'string','$\theta$',...
    'FontSize',20,'color','black');

text(app.UIAxes2_6,'Interpreter','LaTex',...
    'string','$\theta$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_8,'Interpreter','LaTex',...
    'string','$2 \pi$',...
    'FontSize',20,'color','black');

text(app.UIAxes2_7,'Interpreter','LaTex',...
    'string','$(2 \pi)$',...
    'FontSize',16,'color',[0.50,0.50,0.50]);

text(app.UIAxes2_10,'Interpreter','LaTex',...
    'string','$(2 \pi)$',...
    'FontSize',16,'color',[0.50,0.50,0.50]);
text(app.UIAxes2_9,'Interpreter','LaTex',...
    'string','$2 \pi$',...
    'FontSize',20,'color','black');
        end

        % Callback function
        function EditField_2ValueChanged(app, event)

        end

        % Callback function
        function EditField_4ValueChanged(app, event)
%             T = app.EditField_4.Value;
%             W = app.EditField_3.Value;
%             t=-10:0.1:10;
%             X=cos(W*t+T);
%             plot(app.UIAxes,t,X,'LineWidth',1.5)S
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
               
               T2 =(app.EditField_5.Value);
               A=app.EditField_6.Value;
               tt=abs(app.EditField_7.Value);
               if tt==0
                   tt=20;
               end
            W = app.EditField_3.Value*2*pi;
            t=-tt:0.01:tt;
            X=A*cos(W*t+(2*pi*(T2)));
            plot(app.UIAxes,t,X,'LineWidth',1.5)
             aa=(T2)*2*pi;
             strmax = ['-------------------------------------------------------------------->n'];
dash=['|'];
str_r=[' ',num2str(-1)];
str_2=[' ',num2str(1)]

            %% Frequency Coefficients Plot
            Amp=0.5*A;
            stem(app.UIAxes_2,-W,abs(Amp))
            hold(app.UIAxes_2,'on')
            stem(app.UIAxes_2,W,abs(Amp))
            xlim(app.UIAxes_2,[-abs(W)-1 abs(W)+1])
            ylim(app.UIAxes_2,[-abs(Amp)-0.9 abs(Amp)+0.9])
             text(app.UIAxes_2,-abs(W)-1,-abs(Amp)-0.2,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_2,-abs(W),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,abs(W),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            
      
             text(app.UIAxes_2,-abs(W),-Amp-0.1,str_r,'FontSize',11);
             text(app.UIAxes_2,abs(W),-Amp-0.1,str_2,'FontSize',11);
            hold(app.UIAxes_2,'off')


            stem(app.UIAxes_3,-(W),-aa-(angle(A)))
            hold(app.UIAxes_3,'on')
            
            stem(app.UIAxes_3,W,aa+(angle(A)))
            
      text(app.UIAxes_3,-abs(W)-1,-abs(aa+(angle(A)))-2,strmax,'FontSize',11,'color','b');
      text(app.UIAxes_3,-abs(W),-abs(aa+(angle(A)))-2,dash,'FontSize',11,'color','b')
      text(app.UIAxes_3,abs(W),-abs(aa+(angle(A)))-2,dash,'FontSize',11,'color','b')
      
             text(app.UIAxes_3,-abs(W),-abs(aa+(angle(A)))-2,str_r,'FontSize',11);
             text(app.UIAxes_3,abs(W),-abs(aa+(angle(A)))-2,str_2,'FontSize',11);
            xlim(app.UIAxes_3,[-abs(W)-1 abs(W)+1])
            ylim(app.UIAxes_3,[-abs(aa)-(angle(A))-3 abs(aa)+(angle(A))+3])
         

            hold(app.UIAxes_3,'off')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Time Domain Plot'; ''})
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'|D_n|'; ''})
            xlabel(app.UIAxes_2, ' \omega (rad/sec)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, {'\angle D_n'; ''})
            xlabel(app.UIAxes_3, '\omega (rad/sec)')
            ylabel(app.UIAxes_3, 'Phase (radians)')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_4.ZTick = [];
            app.UIAxes2_4.Visible = 'off';
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            app.UIAxes2_5.XTick = [];
            app.UIAxes2_5.YTick = [];
            app.UIAxes2_5.ZTick = [];
            app.UIAxes2_5.Visible = 'off';
            app.UIAxes2_6 = uiaxes(app.UIFigure);
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_6.ZTick = [];
            app.UIAxes2_6.Visible = 'off';
            app.UIAxes2_7 = uiaxes(app.UIFigure);
            app.UIAxes2_7.XTick = [];
            app.UIAxes2_7.YTick = [];
            app.UIAxes2_7.ZTick = [];
            app.UIAxes2_7.Visible = 'off';
            app.UIAxes2_8 = uiaxes(app.UIFigure);
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.YTick = [];
            app.UIAxes2_8.ZTick = [];
            app.UIAxes2_8.Visible = 'off';
            app.UIAxes2_9 = uiaxes(app.UIFigure);
            app.UIAxes2_9.XTick = [];
            app.UIAxes2_9.YTick = [];
            app.UIAxes2_9.ZTick = [];
            app.UIAxes2_9.Visible = 'off';
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            app.UIAxes2_10.XTick = [];
            app.UIAxes2_10.YTick = [];
            app.UIAxes2_10.ZTick = [];
            app.UIAxes2_10.Visible = 'off';
            app.xtcostLabel = uilabel(app.UIFigure);
            app.xtcostLabel.Text = 'x(t)=      cos (               t+              )';
            app.xtAcostLabel = uilabel(app.UIFigure);
            app.xtAcostLabel.Text = 'x(t)=A cos (         t+     )';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [-360 360];
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Limits = [-360 360];
            app.FourierSeriesofaCosineSignalTwoSpiralsLabel = uilabel(app.UIFigure);
            app.FourierSeriesofaCosineSignalTwoSpiralsLabel.Text = 'Fourier Series of a Cosine Signal (Two Spirals)';
            app.SignalsSystemsIRyersonUniversityLabel = uilabel(app.UIFigure);
            app.SignalsSystemsIRyersonUniversityLabel.Text = 'Signals & Systems I - Ryerson University';
            app.CopyRightSoosanBeheshtiYounesSadatNejadLabel = uilabel(app.UIFigure);
            app.CopyRightSoosanBeheshtiYounesSadatNejadLabel.Text = 'CopyRight - Soosan Beheshti & Younes Sadat-Nejad';
            app.Examplext3cos05tLabel = uilabel(app.UIFigure);
            app.Examplext3cos05tLabel.Text = 'Example: x(t)=3 cos ( 0.5               t+               )';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2';
            app.EnterAinHzandLabel = uilabel(app.UIFigure);
            app.EnterAinHzandLabel.Text = 'Enter A,       (in Hz) and                            :';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Limits = [-360 360];
            app.DefineabsolutevaluerangefortimeLabel = uilabel(app.UIFigure);
            app.DefineabsolutevaluerangefortimeLabel.Text = 'Define absolute value range for time:';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.inradiansLabel = uilabel(app.UIFigure);
            app.inradiansLabel.Text = '(in radians)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSofCosineSig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end