classdef MultiCosineFS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField_18                   matlab.ui.control.NumericEditField
        EditField_17                   matlab.ui.control.NumericEditField
        EditField_14                   matlab.ui.control.NumericEditField
        EditField_13                   matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        EditField_11                   matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        DefineabsolutevaluerangefortimeLabel  matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        EnterthevaluesofAinHzandLabel  matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Exxt3cost02cost0Label          matlab.ui.control.Label
        CopyRightSoosanBeheshtiYounesSadatNejadLabel  matlab.ui.control.Label
        SignalsSystemsIRyersonUniversityLabel  matlab.ui.control.Label
        FourierSeriesofSumofTwoCosineSignalsLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        EditField_4                    matlab.ui.control.NumericEditField
        xtAcostAcostLabel              matlab.ui.control.Label
        xtcostcostLabel                matlab.ui.control.Label
        UIAxes2_12                     matlab.ui.control.UIAxes
        UIAxes2_11                     matlab.ui.control.UIAxes
        UIAxes2_10                     matlab.ui.control.UIAxes
        UIAxes2_9                      matlab.ui.control.UIAxes
        UIAxes2_8                      matlab.ui.control.UIAxes
        UIAxes2_7                      matlab.ui.control.UIAxes
        UIAxes2_6                      matlab.ui.control.UIAxes
        UIAxes2_5                      matlab.ui.control.UIAxes
        UIAxes2_4                      matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            text(app.UIAxes2,'Interpreter','LaTex',...
    'string','$\alpha_1$',...
    'FontSize',20,'color','black');

text(app.UIAxes2_2,'Interpreter','LaTex',...
    'string','$\pi$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_8,'Interpreter','LaTex',...
    'string','$\pi$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_3,'Interpreter','LaTex',...
    'string','$\pi$',...
    'FontSize',20,'color',[0.50,0.50,0.50]);
text(app.UIAxes2_4,'Interpreter','LaTex',...
    'string','$\pi$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_5,'Interpreter','LaTex',...
    'string','$\alpha_2$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_9,'Interpreter','LaTex',...
    'string','$\alpha$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_6,'Interpreter','LaTex',...
    'string','$\pi$',...
    'FontSize',20,'color',[0.50,0.50,0.50]);
text(app.UIAxes2_7,'Interpreter','LaTex',...
    'string','$\pi$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_10,'Interpreter','LaTex',...
    'string','$\theta_1$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_11,'Interpreter','LaTex',...
    'string','$\theta_2$',...
    'FontSize',20,'color','black');
text(app.UIAxes2_12,'Interpreter','LaTex',...
    'string','$\theta$',...
    'FontSize',20,'color','black');
        end

        % Callback function
        function EditField_2ValueChanged(app, event)

        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
%             T = app.EditField_4.Value;
%             W = app.EditField_3.Value;
%             t=-10:0.1:10;
%             X=cos(W*t+T);
%             plot(app.UIAxes,t,X,'LineWidth',1.5)
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
              

        T1 = 1;
               T2 = app.EditField_18.Value*pi;
               
               T1_A = 1;
               
               T2_A = app.EditField_17.Value*pi;
               A=app.EditField_6.Value;
               A2=app.EditField_8.Value; 
               tt=abs(app.EditField_7.Value);
               if tt==0
                   tt=20;
               end
            W =(pi* app.EditField_13.Value)/app.EditField_4.Value;
             Per1=W;
            W=W*pi;
            W2=(pi*app.EditField_14.Value)/app.EditField_11.Value;
            Per2=W2;
            W2=W2*pi;
            t=-tt:0.01:tt;
            X=A*cos(W*t+((T2)./T1));
            X2=A2*cos(W2*t+((T2_A)./T1_A));
            plot(app.UIAxes,t,X+X2,'LineWidth',1.5)
 if A==0
          aa=(T2_A./T1_A);
             strmax = ['-------------------------------------------------------------------->n'];
dash=['|'];
str_r=[' ',num2str(-1)];
str_2=[' ',num2str(1)]

            %% Frequency Coefficients Plot
            Amp2=0.5*A2;
            stem(app.UIAxes_2,-W2,abs(Amp2))
            hold(app.UIAxes_2,'on')
            stem(app.UIAxes_2,W2,abs(Amp2))
            xlim(app.UIAxes_2,[-abs(W2)-1 abs(W2)+1])
            ylim(app.UIAxes_2,[-abs(Amp2)-0.9 abs(Amp2)+0.9])
             text(app.UIAxes_2,-abs(W2)-1,-abs(Amp2)-0.2,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_2,-abs(W2),-abs(Amp2)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,abs(W2),-abs(Amp2)-0.2,dash,'FontSize',11,'color','b')
            
      
             text(app.UIAxes_2,-abs(W2),-abs(Amp2)-0.1,str_r,'FontSize',11);
             text(app.UIAxes_2,abs(W2),-abs(Amp2)-0.1,str_2,'FontSize',11);
            hold(app.UIAxes_2,'off')


            stem(app.UIAxes_3,-(W2),-aa-rad2deg(angle(A2)))
            hold(app.UIAxes_3,'on')
            
            stem(app.UIAxes_3,W2,aa+rad2deg(angle(A2)))
          xlim(app.UIAxes_3,[-abs(W2)-1 abs(W2)+1])
            ylim(app.UIAxes_3,[-abs(aa+rad2deg(angle(A2)))-2.5 abs(aa+rad2deg(angle(Amp2)))+2.5])
              
      text(app.UIAxes_3,-abs(W2)-1,-abs(aa+rad2deg(angle(A2)))-2,strmax,'FontSize',11,'color','b');
      text(app.UIAxes_3,-abs(W2),-abs(aa+rad2deg(angle(A2)))-2,dash,'FontSize',11,'color','b')
      text(app.UIAxes_3,abs(W2),-abs(aa+rad2deg(angle(A2)))-2,dash,'FontSize',11,'color','b')
      
             text(app.UIAxes_3,-abs(W2),-abs(aa+rad2deg(angle(A2)))-2,str_r,'FontSize',11);
             text(app.UIAxes_3,abs(W2),-abs(aa+rad2deg(angle(A2)))-2,str_2,'FontSize',11);
   
            hold(app.UIAxes_3,'off')
     
 elseif A2==0
     aa=(T2./T1);
             strmax = ['-------------------------------------------------------------------->n'];
dash=['|'];
str_r=[' ',num2str(-1)];
str_2=[' ',num2str(1)]

            %% Frequency Coefficients Plot
            Amp=0.5*A;
            stem(app.UIAxes_2,-W,abs(Amp))
            hold(app.UIAxes_2,'on')
            stem(app.UIAxes_2,W,abs(Amp))
            xlim(app.UIAxes_2,[-abs(W)-1 abs(W)+1])
            ylim(app.UIAxes_2,[-abs(Amp)-0.9 abs(Amp)+0.9])
             text(app.UIAxes_2,-abs(W)-1,-abs(Amp)-0.2,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_2,-abs(W),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,abs(W),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            
      
             text(app.UIAxes_2,-abs(W),-Amp-0.1,str_r,'FontSize',11);
             text(app.UIAxes_2,abs(W),-Amp-0.1,str_2,'FontSize',11);
            hold(app.UIAxes_2,'off')


            stem(app.UIAxes_3,-(W),-aa-rad2deg(angle(A)))
            hold(app.UIAxes_3,'on')
            
            stem(app.UIAxes_3,W,aa+rad2deg(angle(A)))
            
      text(app.UIAxes_3,-abs(W)-1,-abs(aa+rad2deg(angle(A)))-2,strmax,'FontSize',11,'color','b');
      text(app.UIAxes_3,-abs(W),-abs(aa+rad2deg(angle(A)))-2,dash,'FontSize',11,'color','b')
      text(app.UIAxes_3,abs(W),-abs(aa+rad2deg(angle(A)))-2,dash,'FontSize',11,'color','b')
      
             text(app.UIAxes_3,-abs(W),-abs(aa+rad2deg(angle(A)))-2,str_r,'FontSize',11);
             text(app.UIAxes_3,abs(W),-abs(aa+rad2deg(angle(A)))-2,str_2,'FontSize',11);
            xlim(app.UIAxes_3,[-abs(W)-1 abs(W)+1])
            ylim(app.UIAxes_3,[-abs(aa)-rad2deg(angle(A))-3 abs(aa)+rad2deg(angle(A))+3])
         

            hold(app.UIAxes_3,'off')

 else
     
            %% Frequency Coefficients Plot
            Amp=0.5*A;
            Amp2=0.5*A2
            
            nnn1=app.EditField_13.Value;m1=app.EditField_4.Value;
            nnn2=app.EditField_14.Value;m2=app.EditField_11.Value;
            tt1=(2*m1)/nnn1;
            tt2=(2*m2)/nnn2;
            if tt1==round(tt1) & tt2==round(tt2)
                L=lcm(tt1,tt2);
                n1=L/tt1;n2=L/tt2;
            else
                %L=lcm(nnn1,nnn2)
                if (nnn1/2)==round(nnn1/2)
                    new_nnn1=(nnn1/2)
                    mmm=m1;
                else
                    new_nnn1=nnn1;
                    mmm=m1*2;
                
                end
                if (nnn2/2)==round(nnn2/2)
                    new_nnn2=(nnn2/2)
                    mmm2=m2;
                else
                    new_nnn2=nnn2;
                    mmm2=m2*2;
                end
                if new_nnn2==new_nnn1
                L=lcm(mmm,mmm2);
                else 
                    lcm(new_nnn1,new_nnn2)
                end
                try
                n1=L/tt1;n2=L/tt2;
                catch
                end
            end


            if W==W2
            stem(app.UIAxes_2,-W,abs(Amp)+abs(Amp2))
            hold(app.UIAxes_2,'on')
            stem(app.UIAxes_2,W,abs(Amp)+abs(Amp2))
            xlim(app.UIAxes_2,[-W-1 W+1])
            ylim(app.UIAxes_2,[-abs(Amp)-abs(Amp2)-3 abs(Amp)+abs(Amp2)+3])
               strmax = ['-------------------------------------------------------------------->n'];

 dash=['|'];
 try
str_r=[' ',num2str(-n1)];
str_2=[' ',num2str(n1)];
strB_r=[' ',num2str(-n2)];
strB_2=[' ',num2str(n2)];
            text(app.UIAxes_2,-W-4,-abs(Amp)-abs(Amp2)-2,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_2,-W,-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,W,-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,-W,-abs(Amp)-0.1,str_r,'FontSize',11);
            text(app.UIAxes_2,W,-abs(Amp)-0.1,str_2,'FontSize',11);
          %  text(app.UIAxes_2,-W,-abs(Amp2)-0.1,strB_r,'FontSize',11);
          %  text(app.UIAxes_2,W,-abs(Amp2)-0.1,strB_2,'FontSize',11);        
 catch
 end
            hold(app.UIAxes_2,'off')          
            
            aa=(T2*pi)/T1;
            aa2=(T2_A*pi)/T1_A;
            stem(app.UIAxes_3,-W,-aa-aa2)
            hold(app.UIAxes_3,'on')
            stem(app.UIAxes_3,W,aa+aa2)
            xlim(app.UIAxes_3,[-W-1 W+1])
            ylim(app.UIAxes_3,[-aa-aa2-3 aa+aa2+3])
   
         
            text(app.UIAxes_3,-W-2,-Amp-Amp2,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_3,-W,-Amp-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_3,W,-Amp-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_3,-W,-Amp-0.1,str_r,'FontSize',11);
            text(app.UIAxes_3,W,-Amp-0.1,str_2,'FontSize',11);
            
                     hold(app.UIAxes_3,'off')
             
            else
            stem(app.UIAxes_2,-W,abs(Amp))
            hold(app.UIAxes_2,'on')
            stem(app.UIAxes_2,W,abs(Amp))
            
            stem(app.UIAxes_2,-W2,abs(Amp2))
            stem(app.UIAxes_2,W2,abs(Amp2))
            xlim(app.UIAxes_2,[-W-W2-1 W+W2+1])
            ylim(app.UIAxes_2,[-abs(Amp)-abs(Amp2)-3 abs(Amp)+abs(Amp2)+3])
            strmax = ['-------------------------------------------------------------------->n'];
dash=['|'];
try
str_r=[' ',num2str(-n1)];
str_2=[' ',num2str(n1)]
str2_r=[' ',num2str(-n2)];
str2_2=[' ',num2str(n2)]
            text(app.UIAxes_2,-abs(W)-abs(W2)-1,-abs(Amp)-0.2,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_2,-abs(W),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,abs(W),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,-abs(W2),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,abs(W2),-abs(Amp)-0.2,dash,'FontSize',11,'color','b')
            text(app.UIAxes_2,-abs(W),-abs(Amp)-0.1,str_r,'FontSize',11);
            text(app.UIAxes_2,abs(W),-abs(Amp)-0.1,str_2,'FontSize',11);
             text(app.UIAxes_2,-abs(W2),-abs(Amp)-0.1,str2_r,'FontSize',11);
            text(app.UIAxes_2,abs(W2),-abs(Amp)-0.1,str2_2,'FontSize',11);
end
            hold(app.UIAxes_2,'off')          
            
            aa=(T2*pi)/T1;
            aa2=(T2_A*pi)/T1_A;
            stem(app.UIAxes_3,-W,-aa)
            hold(app.UIAxes_3,'on')
            stem(app.UIAxes_3,W,aa)
            stem(app.UIAxes_3,-W2,-aa2)
            stem(app.UIAxes_3,W2,aa2)
            xlim(app.UIAxes_3,[-W-W2-1 W+W2+1])
            ylim(app.UIAxes_3,[-abs(aa)-abs(aa2)-3 abs(aa)+abs(aa2)+3])
  strmax = ['-------------------------------------------------------------------->n'];
dash=['|'];
try
str_r=[' ',num2str(-n1)];
str_2=[' ',num2str(n1)]
str2_r=[' ',num2str(-n2)];
str2_2=[' ',num2str(n2)]
            text(app.UIAxes_3,-abs(W)-abs(W2)-1,-abs(aa)-abs(aa2)-0.5,strmax,'FontSize',11,'color','b');
            text(app.UIAxes_3,-abs(W),-abs(aa)-abs(aa2)-0.5,dash,'FontSize',11,'color','b')
            text(app.UIAxes_3,abs(W),-abs(aa)-abs(aa2)-0.5,dash,'FontSize',11,'color','b')
            text(app.UIAxes_3,-abs(W2),-abs(aa)-abs(aa2)-0.5,dash,'FontSize',11,'color','b')
            text(app.UIAxes_3,abs(W2),-abs(aa)-abs(aa2)-0.5,dash,'FontSize',11,'color','b')
            text(app.UIAxes_3,-abs(W),-abs(aa)-abs(aa2)-0.5,str_r,'FontSize',11);
            text(app.UIAxes_3,abs(W),-abs(aa)-abs(aa2)-0.5,str_2,'FontSize',11);
             text(app.UIAxes_3,-abs(W2),-abs(aa)-abs(aa2)-0.5,str2_r,'FontSize',11);
            text(app.UIAxes_3,abs(W2),-abs(aa)-abs(aa2)-0.5,str2_2,'FontSize',11);
                      
end       
            hold(app.UIAxes_3,'off') 
            
            end
 
 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Time Domain Plot'; ''})
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'|D_n|'; ''})
            xlabel(app.UIAxes_2, '\omega (rad/sec)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, {'\angle D_n'; ''})
            xlabel(app.UIAxes_3, '\omega (rad/sec)')
            ylabel(app.UIAxes_3, 'Angle (Radians)')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.ZTick = [];
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.ZTick = [];
            app.UIAxes2_3.Visible = 'off';
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_4.ZTick = [];
            app.UIAxes2_4.Visible = 'off';
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            app.UIAxes2_5.XTick = [];
            app.UIAxes2_5.YTick = [];
            app.UIAxes2_5.ZTick = [];
            app.UIAxes2_5.Visible = 'off';
            app.UIAxes2_6 = uiaxes(app.UIFigure);
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_6.ZTick = [];
            app.UIAxes2_6.Visible = 'off';
            app.UIAxes2_7 = uiaxes(app.UIFigure);
            app.UIAxes2_7.XTick = [];
            app.UIAxes2_7.YTick = [];
            app.UIAxes2_7.ZTick = [];
            app.UIAxes2_7.Visible = 'off';
            app.UIAxes2_8 = uiaxes(app.UIFigure);
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.YTick = [];
            app.UIAxes2_8.ZTick = [];
            app.UIAxes2_8.Visible = 'off';
            app.UIAxes2_9 = uiaxes(app.UIFigure);
            app.UIAxes2_9.XTick = [];
            app.UIAxes2_9.YTick = [];
            app.UIAxes2_9.ZTick = [];
            app.UIAxes2_9.Visible = 'off';
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            app.UIAxes2_10.XTick = [];
            app.UIAxes2_10.YTick = [];
            app.UIAxes2_10.ZTick = [];
            app.UIAxes2_10.Visible = 'off';
            app.UIAxes2_11 = uiaxes(app.UIFigure);
            app.UIAxes2_11.XTick = [];
            app.UIAxes2_11.YTick = [];
            app.UIAxes2_11.ZTick = [];
            app.UIAxes2_11.Visible = 'off';
            app.UIAxes2_12 = uiaxes(app.UIFigure);
            app.UIAxes2_12.XTick = [];
            app.UIAxes2_12.YTick = [];
            app.UIAxes2_12.ZTick = [];
            app.UIAxes2_12.Visible = 'off';
            app.xtcostcostLabel = uilabel(app.UIFigure);
            app.xtcostcostLabel.Text = 'x(t)=         cos (           t+           )+        cos (           t+           )';
            app.xtAcostAcostLabel = uilabel(app.UIFigure);
            app.xtAcostAcostLabel.Text = 'x(t)=A cos (     t+     ) + A cos (     t+      )  ';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [1 Inf];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 1;
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '___';
            app.FourierSeriesofSumofTwoCosineSignalsLabel = uilabel(app.UIFigure);
            app.FourierSeriesofSumofTwoCosineSignalsLabel.Text = 'Fourier Series of Sum of Two Cosine Signals';
            app.SignalsSystemsIRyersonUniversityLabel = uilabel(app.UIFigure);
            app.SignalsSystemsIRyersonUniversityLabel.Text = 'Signals & Systems I - Ryerson University';
            app.CopyRightSoosanBeheshtiYounesSadatNejadLabel = uilabel(app.UIFigure);
            app.CopyRightSoosanBeheshtiYounesSadatNejadLabel.Text = 'CopyRight - Soosan Beheshti & Younes Sadat-Nejad';
            app.Exxt3cost02cost0Label = uilabel(app.UIFigure);
            app.Exxt3cost02cost0Label.Text = 'Ex: x(t)=3 cos (          t+0 )+2 cos (         t+0  )';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '___';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'2'; '3'};
            app.EnterthevaluesofAinHzandLabel = uilabel(app.UIFigure);
            app.EnterthevaluesofAinHzandLabel.Text = 'Enter the values of A,       (in Hz) and         : ';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 1;
            app.DefineabsolutevaluerangefortimeLabel = uilabel(app.UIFigure);
            app.DefineabsolutevaluerangefortimeLabel.Text = 'Define absolute value range for time:';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Limits = [-360 360];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '___';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'1'; '5'};
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Value = 5;
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Limits = [1 Inf];
            app.EditField_11.Value = 1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '___';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.Value = 5;
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14.Value = 5;
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_17.Value = 5;
            app.EditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_18.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiCosineFS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end