classdef PeriodicSpirals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Choosefrom2DviewsfortimeplotMenu  matlab.ui.container.Menu
        ChangeViewMenu                matlab.ui.container.Menu
        XYViewMenu                    matlab.ui.container.Menu
        XZViewMenu                    matlab.ui.container.Menu
        YZViewMenu                    matlab.ui.container.Menu
        Switch_2                      matlab.ui.control.ToggleSwitch
        KeeppreviousplotsSwitch       matlab.ui.control.Switch
        KeeppreviousplotsSwitchLabel  matlab.ui.control.Label
        EditField_16                  matlab.ui.control.NumericEditField
        EditField_9                   matlab.ui.control.NumericEditField
        DRotateLabel                  matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        ComputeButton                 matlab.ui.control.Button
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        PeriodicSpiralsLabel          matlab.ui.control.Label
        CopyRightSoosanBeheshtiYounesSadatNejadLabel  matlab.ui.control.Label
        SignalsSystemsIRyersonUniversityLabel  matlab.ui.control.Label
        UIAxes2_26                    matlab.ui.control.UIAxes
        UIAxes2_25                    matlab.ui.control.UIAxes
        UIAxes2_24                    matlab.ui.control.UIAxes
        UIAxes2_23                    matlab.ui.control.UIAxes
        UIAxes2_21                    matlab.ui.control.UIAxes
        UIAxes2_18                    matlab.ui.control.UIAxes
        UIAxes2_11                    matlab.ui.control.UIAxes
        UIAxes2_10                    matlab.ui.control.UIAxes
        UIAxes2_6                     matlab.ui.control.UIAxes
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%text(app.UIAxes2,'Interpreter','LaTex','string','$x(t)= \sum_{n=-\infty}^{\infty} alpha_n e^{j\omega_0 n t}  $','FontSize',14,'color','black');
%text(app.UIAxes2_2,'Interpreter','LaTex','string','$\omega_0 =$','FontSize',14,'color','black');
text(app.UIAxes2_3,'Interpreter','LaTex','string','$\omega_0 =$','FontSize',14,'color','black');
text(app.UIAxes2_6,'Interpreter','LaTex','string','$|D|=$','FontSize',14,'color','black');

text(app.UIAxes2_11,'Interpreter','LaTex','string','$x(t)=D e^{j\omega_0 t}=|D| e^{j(\omega_0 t+\angle (D))}$: ','FontSize',16,'color','black');

text(app.UIAxes2_10,'Interpreter','LaTex','string','Maximum absolute value of t :','FontSize',14,'color','black');

text(app.UIAxes2_18,'Interpreter','LaTex','string','Enter values for  $|D|$ and $\angle (D)$ : ','FontSize',15,'color','black');
text(app.UIAxes2_21,'Interpreter','LaTex','string','$\angle D =$','FontSize',15,'color','black');

text(app.UIAxes2_24,'Interpreter','LaTex','string','The signal is from $ -\infty \textit{ to } +\infty$ ','FontSize',15,'color','black');
text(app.UIAxes2_23,'Interpreter','LaTex','string','but is shown from  $0 \textit{ to } +\infty$','FontSize',15,'color','black');

text(app.UIAxes2_25,'Interpreter','LaTex','string','$2 \pi$','FontSize',15,'color','black');
text(app.UIAxes2_26,'Interpreter','LaTex','string','(rad/sec)','FontSize',15,'color','black');

        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
       % omega=app.EditField.Value;
        maxT=abs(app.EditField_9.Value);
        if maxT==0
            maxT=20;
        end
        t=0:0.01:maxT;
        omegaD=app.EditField_2.Value;
        abs_D=abs(app.EditField_5.Value);
        phase_D=app.EditField_16.Value;
        mmm = app.Switch_2.Value
        switch mmm
        case 'Radian'
            
        case 'Degree'
                phase_D=deg2rad(phase_D)
        end
        i=sqrt(-1);
        D=abs_D*exp(i*phase_D)
        x_1=D.*exp(t.*-1*omegaD*i*2*pi);
        
 x =  abs_D*(cosd(phase_D));
 y =  abs_D*(sind(phase_D));
% S=x+j*y;

        plot3(app.UIAxes,t,real(x_1),imag(x_1),'LineWidth',2);
     hold(app.UIAxes,'on')
     zlabel(app.UIAxes,'Im')
     %   
        view(app.UIAxes,[60,45])
       % xlim(app.UIAxes,[-maxT maxT])       

 %plot3(app.UIAxes,real(D),B,'o','LineWidth',2)
 quiver3(app.UIAxes,0,0,0,0,real(D),imag(D),0,'LineWidth',2)

% plot3(app.UIAxes,0*ones(length(t)),real(x_1),imag(x_1),'--','linewidth',1.2,'color','k')
ylim(app.UIAxes,[-abs( abs_D)-1 abs( abs_D)+1])
zlim(app.UIAxes,[-abs( abs_D)-1 abs( abs_D)+1])

xlim(app.UIAxes,[-0.5 maxT])
% xlim(app.UIAxes,[-abs(maxT) abs( maxT)])
%plot(app.UIAxes_3,real(x_1),imag(x_1))
grid(app.UIAxes,'on')
hhh = app.KeeppreviousplotsSwitch.Value
switch hhh
        case 'Off'
            hold(app.UIAxes,'off');
        case 'On'
end
     

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
   
            if value=='On'
                rotate3d(app.UIAxes,'on');
                drawnow;
            elseif value=='Off'
                rotate3d(app.UIAxes,'off');
                drawnow;
            end
        end

        % Menu selected function: XYViewMenu
        function XYViewMenuSelected(app, event)
             az=0
            vb=90
            view(app.UIAxes,[az,vb])
        end

        % Menu selected function: XZViewMenu
        function XZViewMenuSelected(app, event)
             az=0
            vb=180
            view(app.UIAxes,[az,vb])
        end

        % Menu selected function: YZViewMenu
        function YZViewMenuSelected(app, event)
             az=90
            vb=0
            view(app.UIAxes,[az,vb])
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
          
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
       
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
      
        end

        % Callback function
        function ComputextButtonPushed(app, event)
                    
%        % omega=app.EditField.Value;
% 
%         
%                 maxT=abs(app.EditField_9.Value);
%         t=-maxT:0.01:maxT;
%         
%                 alpha_0=app.EditField_5.Value+i*app.EditField_16.Value;
%         alpha_1=app.EditField_2.Value+i*app.EditField_14.Value;
%         D1=app.EditField_3.Value+i*app.EditField_15.Value;
%         alpha_2=app.EditField_10.Value+i*app.EditField_17.Value; 
%         D2=app.EditField_12.Value+i*app.EditField_18.Value;
%         w_1=app.EditField_6.Value;
%         w1=app.EditField_8.Value;
%         w_2=app.EditField_11.Value;
%         w2=app.EditField_13.Value;
%         
%         x_1=alpha_1*exp(t*-1*w_1*i);
%         x1=D1*exp(t*1*w1*i);
%          x_2=alpha_2*exp(t*-1*w_2*i);
%         x2=D2*exp(t*1*w2*i);
%         
%         Fx=x_1+x2+x_2+x2+(alpha_0*exp(t*0*w2*i));
%         plot3(app.UIAxes,t,real(Fx),imag(Fx),'LineWidth',2);
%          mx=max(real(Fx));
%     %    ylim(app.UIAxes,[-abs(max(real(Fx)))-5.5 abs(max(real(Fx)))+5.5])
%     %    zlim(app.UIAxes,[-abs(max(imag(Fx)))-5.5 abs(max(imag(Fx)))+5.5])
%     %    xlim(app.UIAxes,[-abs(length(Fx))-5.5 abs(length(Fx))+5.5])
%         hold(app.UIAxes_2,'off');
% 
%         view(app.UIAxes,[60,45])
        end

        % Callback function
        function EditField_13ValueChanged(app, event)
      
        end

        % Callback function
        function EditField_10ValueChanged(app, event)
         
        end

        % Callback function
        function EditField_12ValueChanged(app, event)
          
        end

        % Value changed function: KeeppreviousplotsSwitch
        function KeeppreviousplotsSwitchValueChanged(app, event)
value = app.KeeppreviousplotsSwitch.Value;

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Choosefrom2DviewsfortimeplotMenu = uimenu(app.UIFigure);
            app.Choosefrom2DviewsfortimeplotMenu.Text = 'Choose from 2D views for time plot';
            app.ChangeViewMenu = uimenu(app.Choosefrom2DviewsfortimeplotMenu);
            app.ChangeViewMenu.Text = 'Change View';
            app.XYViewMenu = uimenu(app.ChangeViewMenu);
            app.XYViewMenu.MenuSelectedFcn = createCallbackFcn(app, @XYViewMenuSelected, true);
            app.XYViewMenu.Separator = 'on';
            app.XYViewMenu.Text = 'X-Y View';
            app.XZViewMenu = uimenu(app.ChangeViewMenu);
            app.XZViewMenu.MenuSelectedFcn = createCallbackFcn(app, @XZViewMenuSelected, true);
            app.XZViewMenu.Separator = 'on';
            app.XZViewMenu.Text = 'X-Z View';
            app.YZViewMenu = uimenu(app.ChangeViewMenu);
            app.YZViewMenu.MenuSelectedFcn = createCallbackFcn(app, @YZViewMenuSelected, true);
            app.YZViewMenu.Separator = 'on';
            app.YZViewMenu.Text = 'Y-Z View';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'x(t)')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Real')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.ZTick = [];
            app.UIAxes2_3.Visible = 'off';
            app.UIAxes2_6 = uiaxes(app.UIFigure);
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_6.ZTick = [];
            app.UIAxes2_6.Visible = 'off';
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            app.UIAxes2_10.XTick = [];
            app.UIAxes2_10.YTick = [];
            app.UIAxes2_10.ZTick = [];
            app.UIAxes2_10.Visible = 'off';
            app.UIAxes2_11 = uiaxes(app.UIFigure);
            app.UIAxes2_11.XTick = [];
            app.UIAxes2_11.YTick = [];
            app.UIAxes2_11.ZTick = [];
            app.UIAxes2_11.Visible = 'off';
            app.UIAxes2_18 = uiaxes(app.UIFigure);
            app.UIAxes2_18.XTick = [];
            app.UIAxes2_18.YTick = [];
            app.UIAxes2_18.ZTick = [];
            app.UIAxes2_18.Visible = 'off';
            app.UIAxes2_21 = uiaxes(app.UIFigure);
            app.UIAxes2_21.XTick = [];
            app.UIAxes2_21.YTick = [];
            app.UIAxes2_21.ZTick = [];
            app.UIAxes2_21.Visible = 'off';
            app.UIAxes2_23 = uiaxes(app.UIFigure);
            app.UIAxes2_23.XTick = [];
            app.UIAxes2_23.YTick = [];
            app.UIAxes2_23.ZTick = [];
            app.UIAxes2_23.Visible = 'off';
            app.UIAxes2_24 = uiaxes(app.UIFigure);
            app.UIAxes2_24.XTick = [];
            app.UIAxes2_24.YTick = [];
            app.UIAxes2_24.ZTick = [];
            app.UIAxes2_24.Visible = 'off';
            app.UIAxes2_25 = uiaxes(app.UIFigure);
            title(app.UIAxes2_25, 'C')
            app.UIAxes2_25.XTick = [];
            app.UIAxes2_25.YTick = [];
            app.UIAxes2_25.ZTick = [];
            app.UIAxes2_25.Visible = 'off';
            app.UIAxes2_26 = uiaxes(app.UIFigure);
            app.UIAxes2_26.XTick = [];
            app.UIAxes2_26.YTick = [];
            app.UIAxes2_26.ZTick = [];
            app.UIAxes2_26.Visible = 'off';
            app.SignalsSystemsIRyersonUniversityLabel = uilabel(app.UIFigure);
            app.SignalsSystemsIRyersonUniversityLabel.Text = 'Signals & Systems I - Ryerson University';
            app.CopyRightSoosanBeheshtiYounesSadatNejadLabel = uilabel(app.UIFigure);
            app.CopyRightSoosanBeheshtiYounesSadatNejadLabel.Text = 'CopyRight - Soosan Beheshti & Younes Sadat-Nejad';
            app.PeriodicSpiralsLabel = uilabel(app.UIFigure);
            app.PeriodicSpiralsLabel.Text = 'Periodic Spirals (Basis of Fourier Series)';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [-360 360];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute ';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DRotateLabel = uilabel(app.UIFigure);
            app.DRotateLabel.Text = '3D Rotate';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Limits = [-360 360];
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_16.Limits = [-360 360];
            app.KeeppreviousplotsSwitchLabel = uilabel(app.UIFigure);
            app.KeeppreviousplotsSwitchLabel.Text = 'Keep previous plots';
            app.KeeppreviousplotsSwitch = uiswitch(app.UIFigure, 'slider');
            app.KeeppreviousplotsSwitch.ValueChangedFcn = createCallbackFcn(app, @KeeppreviousplotsSwitchValueChanged, true);
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.Items = {'Radian', 'Degree'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Radian';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PeriodicSpirals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end